classdef trip_version2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StartBtn                    matlab.ui.control.Button
        ComfortableWalkingSpeedmsEditFieldLabel  matlab.ui.control.Label
        CWS                         matlab.ui.control.NumericEditField
        BodyWeightkgEditFieldLabel  matlab.ui.control.Label
        BodyW                       matlab.ui.control.NumericEditField
        PauseBtn                    matlab.ui.control.Button
        StopBtn                     matlab.ui.control.Button
        ResumeBtn                   matlab.ui.control.Button
        EndBtn                      matlab.ui.control.Button
        CurrentStatusLabel          matlab.ui.control.Label
        Status                      matlab.ui.control.EditField
        RemindLabel                 matlab.ui.control.Label
        LogFilenameEditFieldLabel   matlab.ui.control.Label
        filename                    matlab.ui.control.EditField
        Event1Label                 matlab.ui.control.Label
        Event1                      matlab.ui.control.TextArea
        Event2TextAreaLabel         matlab.ui.control.Label
        Event2                      matlab.ui.control.TextArea
        Event3TextAreaLabel         matlab.ui.control.Label
        Event3                      matlab.ui.control.TextArea
        Event4TextAreaLabel         matlab.ui.control.Label
        Event4                      matlab.ui.control.TextArea
        Run1Button                  matlab.ui.control.Button
        Run2Button                  matlab.ui.control.Button
        Run3Button                  matlab.ui.control.Button
        Run4Button                  matlab.ui.control.Button
    end


    properties (Access = public)
        treadmill % a tcpclient to communicate with the treadmill
        caccel % comfortable acceleration
        cws
        bodyweight
        absolutepath = "F:\Hui Ting Shih\Perturbation data collection sheet\Stabilometer learning\logs\" % need to change probably 
        allevents = ["L1" "L2" "R1" "R2"]
        orderofevents
        logs = []
        red = [1.00, 0.00, 0.00]
        yellow = [1.00, 1.00, 0.00]
        btn1 = 0
        btn2 = 0
        btn3 = 0
        btn4 = 0
        logfilename
    end

    methods (Access = private)
    
        function str = event2text(app, index)
                strtmp = "";
                currenteventdetails = char(app.orderofevents(index));
                direction = currenteventdetails(1); % L or R
                accerleration = currenteventdetails(2); % 6 or 12
                if direction == 'L'
                    strtmp = strtmp + "Left Belt ";
                else
                    strtmp = strtmp + "Right Belt ";
                end
        
                if accerleration == '1'
                    strtmp = strtmp + "Accel = 6.0 ";
                else 
                    strtmp = strtmp + "Accel = 12.0 ";
                end
                str = strtmp;
        end
        
        function log = runcurrentevent(app, index)
                currenteventdetails = char(app.orderofevents(index));
                direction = currenteventdetails(1); % L or R
                accerleration = currenteventdetails(2); % 6 or 12
                if accerleration == '1'
                    currentaccel = 6;
                else 
                    currentaccel = 12;
                end
                log = trip_v2(app.cws,direction,currentaccel,app.treadmill,app.bodyweight);
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: StartBtn
        function StartBtnButtonPushed(app, event)
            if (app.filename.Value == "")
                app.Status.Value = "Please specify the filename for log!!!";
            else
                app.logfilename = app.absolutepath + app.filename.Value + "_dynamic.txt";
            end
            app.treadmill = tcpclient('localhost', 4000);
            app.Status.Value = "Bertec Treadmill : Connected";
            
            %randomize the events and put them in the event text box areas
            
            app.orderofevents = app.allevents;
            orderofexecute = randperm(4);
            for i = 1:4
                currentevent = app.allevents(orderofexecute(i));
                app.orderofevents(i) = currentevent;
            end
            app.Event1.Value = app.event2text(1);
            app.Event2.Value = app.event2text(2);
            app.Event3.Value = app.event2text(3);
            app.Event4.Value = app.event2text(4);

            
            app.caccel = 0.5;
            app.cws = app.CWS.Value;
            app.bodyweight = app.BodyW.Value;

            tm_set_new(app.treadmill, app.cws, app.caccel); % start the treadmill

        end

        % Value changed function: CWS
        function CWSValueChanged(app, event)
            value = app.CWS.Value;
            app.cws = value;
        end

        % Value changed function: BodyW
        function BodyWValueChanged(app, event)
            value = app.BodyW.Value;
            app.bodyweight = value;
        end

        % Button pushed function: PauseBtn
        function PauseBtnButtonPushed(app, event)
            tm_set_new(app.treadmill, 0, app.caccel);
        end

        % Button pushed function: StopBtn
        function StopBtnButtonPushed(app, event)
            tm_set_new(app.treadmill, 0, 32.767);
        end

        % Button pushed function: ResumeBtn
        function ResumeBtnButtonPushed(app, event)
            tm_set_new(app.treadmill, app.CWS.Value, app.caccel);
        end

        % Button pushed function: EndBtn
        function EndBtnButtonPushed(app, event)
            tm_set_new(app.treadmill, 0, app.caccel);
            clear app.treadmill;
            app.Status.Value = "Bertec Treadmill : DisConnected"; 
            fileID = fopen(app.logfilename,'w');
            formatSpec = '%s \n';
            for i = 1:length(app.logs)
                fprintf(fileID, formatSpec, app.logs(i));
            end
            fclose(fileID);
            str = "Log file saved in " + app.logfilename;
            app.Status.Value = str; 

        end

        % Value changed function: filename
        function filenameValueChanged(app, event)
            value = app.filename.Value;
            app.logfilename = app.absolutepath + value + "_dynamic.txt";
            app.Status.Value = "Thank you for specify filename for log";
        end

        % Button pushed function: Run1Button
        function Run1ButtonPushed(app, event)
            app.Status.Value = "Waiting to Trip ... ... ";
            eventlog = app.runcurrentevent(1);
            %eventlog = app.event2text(1);
            if (rem(app.btn1,2) == 0)
                app.Run1Button.BackgroundColor = app.yellow;
            else
                app.Run1Button.BackgroundColor = app.red;
            end
            app.btn1 = app.btn1 + 1;
            app.Status.Value = "Trip 1 Executed";
            app.logs = [app.logs, eventlog];
        end

        % Button pushed function: Run2Button
        function Run2ButtonPushed(app, event)
            app.Status.Value = "Waiting to Trip ... ... ";
            eventlog = app.runcurrentevent(2);
            %eventlog = app.event2text(2);
            if (rem(app.btn2,2) == 0)
                app.Run2Button.BackgroundColor = app.yellow;
            else
                app.Run2Button.BackgroundColor = app.red;
            end
            app.btn2 = app.btn2 + 1;
            app.Status.Value = "Trip 2 Executed";
            app.logs = [app.logs, eventlog];            
        end

        % Button pushed function: Run3Button
        function Run3ButtonPushed(app, event)
            app.Status.Value = "Waiting to Trip ... ... ";
            eventlog = app.runcurrentevent(3);
            %eventlog = app.event2text(3);
            if (rem(app.btn3,2) == 0)
                app.Run3Button.BackgroundColor = app.yellow;
            else
                app.Run3Button.BackgroundColor = app.red;
            end
            app.btn3 = app.btn3 + 1;
            app.Status.Value = "Trip 3 Executed";
            app.logs = [app.logs, eventlog];
        end

        % Button pushed function: Run4Button
        function Run4ButtonPushed(app, event)
            app.Status.Value = "Waiting to Trip ... ... ";
            eventlog = app.runcurrentevent(4);
            %eventlog = app.event2text(4);
            if (rem(app.btn4,2) == 0)
                app.Run4Button.BackgroundColor = app.yellow;
            else
                app.Run4Button.BackgroundColor = app.red;
            end
            app.btn4 = app.btn4 + 1;
            app.Status.Value = "Trip 4 Executed";
            app.logs = [app.logs, eventlog];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartBtn = uibutton(app.UIFigure, 'push');
            app.StartBtn.ButtonPushedFcn = createCallbackFcn(app, @StartBtnButtonPushed, true);
            app.StartBtn.Text = 'Start Experiment (treadmill will start)';
            app.ComfortableWalkingSpeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.ComfortableWalkingSpeedmsEditFieldLabel.Text = 'Comfortable Walking Speed (m/s)';
            app.CWS = uieditfield(app.UIFigure, 'numeric');
            app.CWS.Limits = [0 3];
            app.CWS.ValueChangedFcn = createCallbackFcn(app, @CWSValueChanged, true);
            app.CWS.Value = 1.1;
            app.BodyWeightkgEditFieldLabel = uilabel(app.UIFigure);
            app.BodyWeightkgEditFieldLabel.Text = 'Body Weight (kg)';
            app.BodyW = uieditfield(app.UIFigure, 'numeric');
            app.BodyW.Limits = [0 Inf];
            app.BodyW.ValueChangedFcn = createCallbackFcn(app, @BodyWValueChanged, true);
            app.BodyW.Value = 64.4;
            app.PauseBtn = uibutton(app.UIFigure, 'push');
            app.PauseBtn.ButtonPushedFcn = createCallbackFcn(app, @PauseBtnButtonPushed, true);
            app.PauseBtn.Text = 'Pause';
            app.StopBtn = uibutton(app.UIFigure, 'push');
            app.StopBtn.ButtonPushedFcn = createCallbackFcn(app, @StopBtnButtonPushed, true);
            app.StopBtn.Text = 'Emergency STOP';
            app.ResumeBtn = uibutton(app.UIFigure, 'push');
            app.ResumeBtn.ButtonPushedFcn = createCallbackFcn(app, @ResumeBtnButtonPushed, true);
            app.ResumeBtn.Text = 'Resume';
            app.EndBtn = uibutton(app.UIFigure, 'push');
            app.EndBtn.ButtonPushedFcn = createCallbackFcn(app, @EndBtnButtonPushed, true);
            app.EndBtn.Text = 'End Experiment';
            app.CurrentStatusLabel = uilabel(app.UIFigure);
            app.CurrentStatusLabel.Text = 'Current Status';
            app.Status = uieditfield(app.UIFigure, 'text');
            app.RemindLabel = uilabel(app.UIFigure);
            app.RemindLabel.Text = 'Please specify the following before hit "Start Experiment":';
            app.LogFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.LogFilenameEditFieldLabel.Text = 'Log Filename:';
            app.filename = uieditfield(app.UIFigure, 'text');
            app.filename.ValueChangedFcn = createCallbackFcn(app, @filenameValueChanged, true);
            app.filename.Value = 'IC007_day3';
            app.Event1Label = uilabel(app.UIFigure);
            app.Event1Label.Text = 'Event1:';
            app.Event1 = uitextarea(app.UIFigure);
            app.Event2TextAreaLabel = uilabel(app.UIFigure);
            app.Event2TextAreaLabel.Text = 'Event2:';
            app.Event2 = uitextarea(app.UIFigure);
            app.Event3TextAreaLabel = uilabel(app.UIFigure);
            app.Event3TextAreaLabel.Text = 'Event3:';
            app.Event3 = uitextarea(app.UIFigure);
            app.Event4TextAreaLabel = uilabel(app.UIFigure);
            app.Event4TextAreaLabel.Text = 'Event4:';
            app.Event4 = uitextarea(app.UIFigure);
            app.Run1Button = uibutton(app.UIFigure, 'push');
            app.Run1Button.ButtonPushedFcn = createCallbackFcn(app, @Run1ButtonPushed, true);
            app.Run1Button.Text = 'Trip1';
            app.Run2Button = uibutton(app.UIFigure, 'push');
            app.Run2Button.ButtonPushedFcn = createCallbackFcn(app, @Run2ButtonPushed, true);
            app.Run2Button.Text = 'Trip2';
            app.Run3Button = uibutton(app.UIFigure, 'push');
            app.Run3Button.ButtonPushedFcn = createCallbackFcn(app, @Run3ButtonPushed, true);
            app.Run3Button.Text = 'Trip3';
            app.Run4Button = uibutton(app.UIFigure, 'push');
            app.Run4Button.ButtonPushedFcn = createCallbackFcn(app, @Run4ButtonPushed, true);
            app.Run4Button.Text = 'Trip4';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trip_version2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end